classdef actionPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InductancePanel             matlab.ui.container.Panel
        LEditField_2Label           matlab.ui.control.Label
        LEditField                  matlab.ui.control.NumericEditField
        CalculerInductanceCheckBox  matlab.ui.control.CheckBox
        Label_2                     matlab.ui.control.Label
        muEditField                 matlab.ui.control.NumericEditField
        mHLabel                     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        NSpinnerLabel               matlab.ui.control.Label
        NSpinner                    matlab.ui.control.Spinner
        spiresLabel                 matlab.ui.control.Label
        oet4x10HmLabel              matlab.ui.control.Label
        HmLabel                     matlab.ui.control.Label
        ALabel                      matlab.ui.control.Label
        AEditField                  matlab.ui.control.NumericEditField
        slNEditFieldLabel           matlab.ui.control.Label
        slNEditField                matlab.ui.control.NumericEditField
        mmLabel                     matlab.ui.control.Label
        cmLabel                     matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        AnnulerButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
        LValue;
        lValue;
    end
    
    methods (Access = private)
        
        function calculateInductance(app)
            app.lValue = app.slNEditField.Value*app.NSpinner.Value;
            app.LValue = ((app.muEditField.Value*(app.NSpinner.Value^2))*app.AEditField.Value)/(app.lValue);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, L, mu, N, s, A)
            app.CalculerInductanceCheckBox.Value = true;
            
            app.CallingApp = mainapp;
            
            % Set init values in UI
            set(app.LEditField,'Value',L);
            set(app.muEditField,'Value',mu);
            set(app.NSpinner,'Value',N);
            set(app.slNEditField,'Value',s);
            set(app.AEditField,'Value',A);
            
            % Set values for inductance calculations to ON
            set(app.LEditField,'Enable',false);
            set(app.muEditField,'Enable',true);
            set(app.NSpinner,'Enable',true);
            set(app.slNEditField,'Enable',true);
            set(app.AEditField,'Enable',true);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.CalculerInductanceCheckBox.Value 
                if (app.muEditField.Value == 0) || (app.slNEditField.Value == 0) || ...
                        (app.AEditField.Value == 0) || (app.NSpinner.Value == 0)
                    uialert(app.UIFigure,"Une des valeurs n'est pas adéquate",'Erreur',"Icon",'error');
                    return
                else
                    calculateInductance(app);
                end
            else
                if app.LEditField.Value == 0
                    uialert(app.UIFigure,"La valeur de L est nulle",'Erreur',"Icon",'error');
                    return
                end
                app.LValue = app.LEditField.Value;
            end
            saveActionPlusMethod(app.CallingApp,app.LValue,app.lValue,app.muEditField.Value,...
                                 app.NSpinner.Value,app.AEditField.Value);
            delete(app);
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: CalculerInductanceCheckBox
        function CalculerInductanceCheckBoxValueChanged(app, event)
            value = app.CalculerInductanceCheckBox.Value;
            if value == true
                % Set values for inductance calculations to ON
                set(app.LEditField,'Enable',false);
                set(app.muEditField,'Enable',true);
                set(app.NSpinner,'Enable',true);
                set(app.slNEditField,'Enable',true);
                set(app.AEditField,'Enable',true);
            else
                % Set values for inductance calculations to ON
                set(app.LEditField,'Enable',true);
                set(app.muEditField,'Enable',false);
                set(app.NSpinner,'Enable',false);
                set(app.slNEditField,'Enable',false);
                set(app.AEditField,'Enable',false);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InductancePanel = uipanel(app.UIFigure);
            app.InductancePanel.Title = 'Inductance';
            app.LEditField_2Label = uilabel(app.InductancePanel);
            app.LEditField_2Label.Text = 'L =';
            app.LEditField = uieditfield(app.InductancePanel, 'numeric');
            app.CalculerInductanceCheckBox = uicheckbox(app.InductancePanel);
            app.CalculerInductanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculerInductanceCheckBoxValueChanged, true);
            app.CalculerInductanceCheckBox.Text = 'Calculer';
            app.Label_2 = uilabel(app.InductancePanel);
            app.Label_2.Text = 'μᵣ =';
            app.muEditField = uieditfield(app.InductancePanel, 'numeric');
            app.mHLabel = uilabel(app.InductancePanel);
            app.mHLabel.Text = 'mH';
            app.Image = uiimage(app.InductancePanel);
            app.Image.ImageSource = 'equL.JPG';
            app.NSpinnerLabel = uilabel(app.InductancePanel);
            app.NSpinnerLabel.Text = 'N = ';
            app.NSpinner = uispinner(app.InductancePanel);
            app.spiresLabel = uilabel(app.InductancePanel);
            app.spiresLabel.Text = 'spires';
            app.oet4x10HmLabel = uilabel(app.InductancePanel);
            app.oet4x10HmLabel.Text = 'où   μ = μₒμᵣ   et   μₒ = 4π x 10⁻⁷   H/m';
            app.HmLabel = uilabel(app.InductancePanel);
            app.HmLabel.Text = 'H/m';
            app.ALabel = uilabel(app.InductancePanel);
            app.ALabel.Text = 'A =';
            app.AEditField = uieditfield(app.InductancePanel, 'numeric');
            app.slNEditFieldLabel = uilabel(app.InductancePanel);
            app.slNEditFieldLabel.Text = 's = l/N =';
            app.slNEditField = uieditfield(app.InductancePanel, 'numeric');
            app.mmLabel = uilabel(app.InductancePanel);
            app.mmLabel.Text = 'mm';
            app.cmLabel = uilabel(app.InductancePanel);
            app.cmLabel.Text = 'cm²';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AnnulerButton = uibutton(app.UIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = actionPlus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end