classdef captPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ContrleurPIDPanel      matlab.ui.container.Panel
        Image                  matlab.ui.control.Image
        KpEditFieldLabel       matlab.ui.control.Label
        KpEditField            matlab.ui.control.NumericEditField
        KiEditFieldLabel       matlab.ui.control.Label
        KiEditField            matlab.ui.control.NumericEditField
        KdEditFieldLabel       matlab.ui.control.Label
        KdEditField            matlab.ui.control.NumericEditField
        TfEditFieldLabel       matlab.ui.control.Label
        TfEditField            matlab.ui.control.NumericEditField
        ConstantedetempsLabel  matlab.ui.control.Label
        OKButton               matlab.ui.control.Button
        AnnulerButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Kp, Ki, Kd, Tf)
            app.CallingApp = mainapp;
            
            % Set default values in edit fields
            app.KpEditField.Value = Kp;
            app.KiEditField.Value = Ki;
            app.KdEditField.Value = Kd;
            app.TfEditField.Value = Tf;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if ((app.KpEditField.Value == 0) && (app.KiEditField.Value == 0) && (app.KdEditField.Value == 0))
                uialert(app.UIFigure,"Tous les gains sont nuls",'Erreur',"Icon",'error');
                return
            end
            if app.TfEditField.Value == 0
                uialert(app.UIFigure,"Constante de temps nulle",'Erreur',"Icon",'error');
                return
            end
            saveCaptPlusMethod(app.CallingApp,app.KpEditField.Value,app.KiEditField.Value,app.KdEditField.Value,app.TfEditField.Value);
            delete(app);
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ContrleurPIDPanel = uipanel(app.UIFigure);
            app.ContrleurPIDPanel.Title = 'Contrôleur PID';
            app.Image = uiimage(app.ContrleurPIDPanel);
            app.Image.ImageSource = 'equPID.JPG';
            app.KpEditFieldLabel = uilabel(app.ContrleurPIDPanel);
            app.KpEditFieldLabel.Text = 'Kp =';
            app.KpEditField = uieditfield(app.ContrleurPIDPanel, 'numeric');
            app.KiEditFieldLabel = uilabel(app.ContrleurPIDPanel);
            app.KiEditFieldLabel.Text = 'Ki =';
            app.KiEditField = uieditfield(app.ContrleurPIDPanel, 'numeric');
            app.KdEditFieldLabel = uilabel(app.ContrleurPIDPanel);
            app.KdEditFieldLabel.Text = 'Kd =';
            app.KdEditField = uieditfield(app.ContrleurPIDPanel, 'numeric');
            app.TfEditFieldLabel = uilabel(app.ContrleurPIDPanel);
            app.TfEditFieldLabel.Text = 'Tf =';
            app.TfEditField = uieditfield(app.ContrleurPIDPanel, 'numeric');
            app.ConstantedetempsLabel = uilabel(app.ContrleurPIDPanel);
            app.ConstantedetempsLabel.Text = 'Constante de temps :';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AnnulerButton = uibutton(app.UIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = captPlus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end