classdef lamePlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ModuledeYoungPanel           matlab.ui.container.Panel
        EEditFieldLabel              matlab.ui.control.Label
        EEditField                   matlab.ui.control.NumericEditField
        CalculerModuleYoungCheckBox  matlab.ui.control.CheckBox
        LEditFieldLabel              matlab.ui.control.Label
        LEditField                   matlab.ui.control.NumericEditField
        ModuleYoungImage             matlab.ui.control.Image
        mLabel                       matlab.ui.control.Label
        mLabel_2                     matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        MuEditField                  matlab.ui.control.NumericEditField
        NmLabel                      matlab.ui.control.Label
        RLabel                       matlab.ui.control.Label
        RnEditField                  matlab.ui.control.NumericEditField
        wEditFieldLabel              matlab.ui.control.Label
        wnEditField                  matlab.ui.control.NumericEditField
        radsLabel                    matlab.ui.control.Label
        mLabel_7                     matlab.ui.control.Label
        MomentdairePanel             matlab.ui.container.Panel
        JEditField_2Label            matlab.ui.control.Label
        JEditField                   matlab.ui.control.NumericEditField
        CalculerMomentdAireCheckBox  matlab.ui.control.CheckBox
        tEditField_2Label            matlab.ui.control.Label
        tEditField                   matlab.ui.control.NumericEditField
        MomentdAireImage             matlab.ui.control.Image
        mLabel_4                     matlab.ui.control.Label
        mLabel_5                     matlab.ui.control.Label
        dEditField_2Label            matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        mLabel_6                     matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        AnnulerButton                matlab.ui.control.Button
        AttnuationPanel              matlab.ui.container.Panel
        Label_2                      matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        AttnuationImage              matlab.ui.control.Image
        CalculerAttnuationCheckBox   matlab.ui.control.CheckBox
        sLabel                       matlab.ui.control.Label
        kgsLabel                     matlab.ui.control.Label
        kgLabel                      matlab.ui.control.Label
        bEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        mEditFieldLabel              matlab.ui.control.Label
        mEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp;
        EValue;
        JValue;
        AValue;
    end
    
    methods (Access = private)
        
        function calculateModuleYoung(app)
            app.EValue = (app.MuEditField.Value*(app.wnEditField.Value^2))/(app.JValue*((app.RnEditField.Value/app.LEditField.Value)^4));
        end
        
        function calculateMomentdAire(app)
            app.JValue = (app.tEditField.Value*(app.dEditField.Value^3))/12;
        end
        
        function calculateAttnuation(app)
            app.AValue = app.bEditField.Value/app.mEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, E, L, Mu, Rn, wn, J, t, d, A, m, b)
            % Set checkboxes to true
            app.CalculerMomentdAireCheckBox.Value = true;
            app.CalculerModuleYoungCheckBox.Value = true;
            app.CalculerAttnuationCheckBox.Value = true;
            
            app.CallingApp = mainapp;
            
            % Set init values in UI
            set(app.EEditField,'Value',E);
            set(app.LEditField,'Value',L);
            set(app.MuEditField,'Value',Mu);
            set(app.RnEditField,'Value',Rn);
            set(app.wnEditField,'Value',wn);
            
            set(app.JEditField,'Value',J);
            set(app.tEditField,'Value',t);
            set(app.dEditField,'Value',d);
            
            set(app.AEditField,'Value',A);
            set(app.mEditField,'Value',m);
            set(app.bEditField,'Value',b);
            
            % Set values for Young's modulus calculations to ON
            set(app.EEditField,'Enable',false);
            set(app.LEditField,'Enable',true);
            set(app.MuEditField,'Enable',true);
            set(app.RnEditField,'Enable',true);
            set(app.wnEditField,'Enable',true);
            
            % Set values for  calculations to ON
            set(app.JEditField,'Enable',false);
            set(app.tEditField,'Enable',true);
            set(app.dEditField,'Enable',true);
            
            % Set values for API interaction to false
            set(app.AEditField,'Enable',false);
            set(app.mEditField,'Enable',true);
            set(app.bEditField,'Enable',true);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.CalculerMomentdAireCheckBox.Value 
                if (app.tEditField.Value == 0) || (app.dEditField.Value == 0)
                    uialert(app.UIFigure,"Une des valeurs n'est pas adéquate",'Erreur',"Icon",'error');
                    return
                else
                    calculateMomentdAire(app);
                end
            else
                if app.JEditField.Value == 0
                    uialert(app.UIFigure,"La valeur de J est nulle",'Erreur',"Icon",'error');
                    return
                end
                app.JValue = app.JEditField.Value;
            end
            if app.CalculerModuleYoungCheckBox.Value
                if (app.LEditField.Value == 0) || (app.MuEditField.Value == 0) ...
                        || (app.RnEditField.Value == 0) || (app.wnEditField.Value == 0)
                    uialert(app.UIFigure,"Une des valeurs n'est pas adéquate",'Erreur',"Icon",'error');
                    return
                else
                    calculateModuleYoung(app);
                end
            else
                if app.EEditField.Value == 0
                    uialert(app.UIFigure,"La valeur de E est nulle",'Erreur',"Icon",'error');
                    return
                end
                app.EValue = app.EEditField.Value;
            end
            if app.CalculerAttnuationCheckBox.Value 
                if (app.bEditField.Value == 0) || (app.mEditField.Value == 0)
                    uialert(app.UIFigure,"Une des valeurs n'est pas adéquate",'Erreur',"Icon",'error');
                    return
                else
                    calculateAttnuation(app);
                end
            else
                if app.AEditField.Value == 0
                    uialert(app.UIFigure,"La valeur de Gamma est nulle",'Erreur',"Icon",'error');
                    return
                end
                app.AValue = app.AEditField.Value;
            end
            saveLamePlusMethod(app.CallingApp,app.EValue,app.JValue,app.AValue,app.wnEditField.Value,...
                app.MuEditField.Value,app.tEditField.Value,app.dEditField.Value,app.bEditField.Value,...
                app.mEditField.Value);
            delete(app);
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: CalculerModuleYoungCheckBox
        function CalculerModuleYoungCheckBoxValueChanged(app, event)
            value = app.CalculerModuleYoungCheckBox.Value;
            if value == true
                % Set values for API interaction to false
                set(app.EEditField,'Enable',false);
                set(app.LEditField,'Enable',true);
                set(app.MuEditField,'Enable',true);
                set(app.RnEditField,'Enable',true);
                set(app.wnEditField,'Enable',true);
            else
                % Set values for API interaction to false
                set(app.EEditField,'Enable',true);
                set(app.LEditField,'Enable',false);
                set(app.MuEditField,'Enable',false);
                set(app.RnEditField,'Enable',false);
                set(app.wnEditField,'Enable',false);
            end
        end

        % Value changed function: CalculerMomentdAireCheckBox
        function CalculerMomentdAireCheckBoxValueChanged(app, event)
            value = app.CalculerMomentdAireCheckBox.Value;
            if value == true
                % Set values for API interaction to false
                set(app.JEditField,'Enable',false);
                set(app.tEditField,'Enable',true);
                set(app.dEditField,'Enable',true);
            else
                % Set values for API interaction to false
                set(app.JEditField,'Enable',true);
                set(app.tEditField,'Enable',false);
                set(app.dEditField,'Enable',false);
            end
        end

        % Value changed function: CalculerAttnuationCheckBox
        function CalculerAttnuationCheckBoxValueChanged(app, event)
            value = app.CalculerAttnuationCheckBox.Value;
            if value == true
                % Set values for API interaction to false
                set(app.AEditField,'Enable',false);
                set(app.mEditField,'Enable',true);
                set(app.bEditField,'Enable',true);
            else
                % Set values for API interaction to false
                set(app.AEditField,'Enable',true);
                set(app.mEditField,'Enable',false);
                set(app.bEditField,'Enable',false);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ModuledeYoungPanel = uipanel(app.UIFigure);
            app.ModuledeYoungPanel.Title = 'Module de Young';
            app.EEditFieldLabel = uilabel(app.ModuledeYoungPanel);
            app.EEditFieldLabel.Text = 'E =';
            app.EEditField = uieditfield(app.ModuledeYoungPanel, 'numeric');
            app.CalculerModuleYoungCheckBox = uicheckbox(app.ModuledeYoungPanel);
            app.CalculerModuleYoungCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculerModuleYoungCheckBoxValueChanged, true);
            app.CalculerModuleYoungCheckBox.Text = 'Calculer';
            app.LEditFieldLabel = uilabel(app.ModuledeYoungPanel);
            app.LEditFieldLabel.Text = 'L =';
            app.LEditField = uieditfield(app.ModuledeYoungPanel, 'numeric');
            app.ModuleYoungImage = uiimage(app.ModuledeYoungPanel);
            app.ModuleYoungImage.ImageSource = 'equE.JPG';
            app.mLabel = uilabel(app.ModuledeYoungPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.ModuledeYoungPanel);
            app.mLabel_2.Text = 'm';
            app.Label = uilabel(app.ModuledeYoungPanel);
            app.Label.Text = 'μ =';
            app.MuEditField = uieditfield(app.ModuledeYoungPanel, 'numeric');
            app.NmLabel = uilabel(app.ModuledeYoungPanel);
            app.NmLabel.Text = ' N/m²';
            app.RLabel = uilabel(app.ModuledeYoungPanel);
            app.RLabel.Text = 'Rₙ =';
            app.RnEditField = uieditfield(app.ModuledeYoungPanel, 'numeric');
            app.wEditFieldLabel = uilabel(app.ModuledeYoungPanel);
            app.wEditFieldLabel.Text = 'wₙ =';
            app.wnEditField = uieditfield(app.ModuledeYoungPanel, 'numeric');
            app.radsLabel = uilabel(app.ModuledeYoungPanel);
            app.radsLabel.Text = 'rad/s';
            app.mLabel_7 = uilabel(app.ModuledeYoungPanel);
            app.mLabel_7.Text = 'm';
            app.MomentdairePanel = uipanel(app.UIFigure);
            app.MomentdairePanel.Title = 'Moment d''aire';
            app.JEditField_2Label = uilabel(app.MomentdairePanel);
            app.JEditField_2Label.Text = 'J =';
            app.JEditField = uieditfield(app.MomentdairePanel, 'numeric');
            app.CalculerMomentdAireCheckBox = uicheckbox(app.MomentdairePanel);
            app.CalculerMomentdAireCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculerMomentdAireCheckBoxValueChanged, true);
            app.CalculerMomentdAireCheckBox.Text = 'Calculer';
            app.tEditField_2Label = uilabel(app.MomentdairePanel);
            app.tEditField_2Label.Text = 't =';
            app.tEditField = uieditfield(app.MomentdairePanel, 'numeric');
            app.MomentdAireImage = uiimage(app.MomentdairePanel);
            app.MomentdAireImage.ImageSource = 'equJ.JPG';
            app.mLabel_4 = uilabel(app.MomentdairePanel);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.MomentdairePanel);
            app.mLabel_5.Text = 'm';
            app.dEditField_2Label = uilabel(app.MomentdairePanel);
            app.dEditField_2Label.Text = 'd =';
            app.dEditField = uieditfield(app.MomentdairePanel, 'numeric');
            app.mLabel_6 = uilabel(app.MomentdairePanel);
            app.mLabel_6.Text = ' m⁴';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AnnulerButton = uibutton(app.UIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.AttnuationPanel = uipanel(app.UIFigure);
            app.AttnuationPanel.Title = 'Atténuation';
            app.Label_2 = uilabel(app.AttnuationPanel);
            app.Label_2.Text = 'γ =';
            app.AEditField = uieditfield(app.AttnuationPanel, 'numeric');
            app.AttnuationImage = uiimage(app.AttnuationPanel);
            app.AttnuationImage.ImageSource = 'equA.JPG';
            app.CalculerAttnuationCheckBox = uicheckbox(app.AttnuationPanel);
            app.CalculerAttnuationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculerAttnuationCheckBoxValueChanged, true);
            app.CalculerAttnuationCheckBox.Text = 'Calculer';
            app.sLabel = uilabel(app.AttnuationPanel);
            app.sLabel.Text = 's⁻¹';
            app.kgsLabel = uilabel(app.AttnuationPanel);
            app.kgsLabel.Text = 'kg/s';
            app.kgLabel = uilabel(app.AttnuationPanel);
            app.kgLabel.Text = 'kg';
            app.bEditFieldLabel = uilabel(app.AttnuationPanel);
            app.bEditFieldLabel.Text = 'b =';
            app.bEditField = uieditfield(app.AttnuationPanel, 'numeric');
            app.mEditFieldLabel = uilabel(app.AttnuationPanel);
            app.mEditFieldLabel.Text = 'm =';
            app.mEditField = uieditfield(app.AttnuationPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lamePlus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end