classdef mainCAO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulateurdebalanceUIFigure    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationTab                  matlab.ui.container.Tab
        UIAnim                         matlab.ui.control.UIAxes
        Lamp                           matlab.ui.control.Lamp
        ParamtresnumismatiquesLabel    matlab.ui.control.Label
        UICoinTable                    matlab.ui.control.Table
        AmorcerlasimulationButton      matlab.ui.control.Button
        AddPanel                       matlab.ui.container.Panel
        AjouterButton                  matlab.ui.control.Button
        DeviseDropDown_2Label          matlab.ui.control.Label
        DeviseDropDown                 matlab.ui.control.DropDown
        MontantLabel                   matlab.ui.control.Label
        MontantDropDown                matlab.ui.control.DropDown
        QuantitEditField_2Label        matlab.ui.control.Label
        QuantitEditField               matlab.ui.control.NumericEditField
        AnneEditFieldLabel             matlab.ui.control.Label
        AnneEditField                  matlab.ui.control.NumericEditField
        RemPanel                       matlab.ui.container.Panel
        RetirerButton                  matlab.ui.control.Button
        LigneDropDownLabel             matlab.ui.control.Label
        LigneDropDown                  matlab.ui.control.DropDown
        APIAddPanel                    matlab.ui.container.Panel
        APIAjouterButton               matlab.ui.control.Button
        APIEditField_1                 matlab.ui.control.NumericEditField
        DotLabel                       matlab.ui.control.Label
        APIEditField_2                 matlab.ui.control.NumericEditField
        DotLabel_2                     matlab.ui.control.Label
        APIEditField_3                 matlab.ui.control.NumericEditField
        IdentifiantAPILabel            matlab.ui.control.Label
        ManualCheckBox                 matlab.ui.control.CheckBox
        APICheckBox                    matlab.ui.control.CheckBox
        ExcelLabel                     matlab.ui.control.Label
        APILabel                       matlab.ui.control.Label
        PourtrouverunepicedemonnaieLabel  matlab.ui.control.Label
        urlNumismatButton              matlab.ui.control.Button
        GraphTabGroup                  matlab.ui.container.TabGroup
        MasseTab                       matlab.ui.container.Tab
        MasseAxes                      matlab.ui.control.UIAxes
        PositionTab                    matlab.ui.container.Tab
        PositionAxes                   matlab.ui.control.UIAxes
        TensionTab                     matlab.ui.container.Tab
        TensionAxes                    matlab.ui.control.UIAxes
        CourantTab                     matlab.ui.container.Tab
        CourantAxes                    matlab.ui.control.UIAxes
        DuredesimulationLabel          matlab.ui.control.Label
        NombredchantillonsEditFieldLabel  matlab.ui.control.Label
        NombredchantillonsEditField    matlab.ui.control.NumericEditField
        TempsparchantillonusEditFieldLabel  matlab.ui.control.Label
        TempsparchantillonusEditField  matlab.ui.control.NumericEditField
        ActiverbalayageCheckBox        matlab.ui.control.CheckBox
        ConfigurationTab               matlab.ui.container.Tab
        DimensionsdelabalancePanel     matlab.ui.container.Panel
        LongueurcmEditFieldLabel       matlab.ui.control.Label
        LongueurBalanceField           matlab.ui.control.NumericEditField
        LargeurcmEditFieldLabel        matlab.ui.control.Label
        LargeurBalanceField            matlab.ui.control.NumericEditField
        HauteurcmEditFieldLabel        matlab.ui.control.Label
        HauteurBalanceField            matlab.ui.control.NumericEditField
        PositionduplateauSliderLabel   matlab.ui.control.Label
        PositionduplateauSlider        matlab.ui.control.Slider
        UIDimension                    matlab.ui.control.UIAxes
        GainamplificateurLabel         matlab.ui.control.Label
        GainAmplificateurEditField     matlab.ui.control.NumericEditField
        LameflexiblePanel              matlab.ui.container.Panel
        paisseurmmLabel                matlab.ui.control.Label
        EpaisseurLameField             matlab.ui.control.NumericEditField
        MassegEditFieldLabel           matlab.ui.control.Label
        MasseLameField                 matlab.ui.control.NumericEditField
        UILameAxes                     matlab.ui.control.UIAxes
        LongueurcmLabel                matlab.ui.control.Label
        LongueurLameField              matlab.ui.control.NumericEditField
        LargeurcmLabel                 matlab.ui.control.Label
        LargeurLameField               matlab.ui.control.NumericEditField
        LamePlusButton                 matlab.ui.control.Button
        CapteurcapacitifPanel          matlab.ui.container.Panel
        UICaptAxes                     matlab.ui.control.UIAxes
        EspacementmmSliderLabel        matlab.ui.control.Label
        EspacementmmSlider             matlab.ui.control.Slider
        CaptPlusButton                 matlab.ui.control.Button
        V_EditFieldLabel               matlab.ui.control.Label
        VimEditField                   matlab.ui.control.NumericEditField
        VLabel                         matlab.ui.control.Label
        VthEditField                   matlab.ui.control.NumericEditField
        ArchivePanel                   matlab.ui.container.Panel
        HistoriquedesconfigurationsListBoxLabel  matlab.ui.control.Label
        HistoriquedesconfigurationsListBox  matlab.ui.control.ListBox
        ParcourirLoadButton            matlab.ui.control.Button
        LoadEditField                  matlab.ui.control.EditField
        ChargeruneautreconfigurationLabel  matlab.ui.control.Label
        NettoyerhistoriqueButton       matlab.ui.control.Button
        AjouterConfigButton            matlab.ui.control.Button
        ChargerlaslectionButton        matlab.ui.control.Button
        SquencedesimulationPanel       matlab.ui.container.Panel
        vnementsLabel                  matlab.ui.control.Label
        UIEventTable                   matlab.ui.control.Table
        AjoutervenementButton          matlab.ui.control.Button
        EnelvervenementButton          matlab.ui.control.Button
        ParcourirLoadSimuButton        matlab.ui.control.Button
        LoadSimuEditField              matlab.ui.control.EditField
        ChargerunesimulationLabel      matlab.ui.control.Label
        ActionneurlineairePanel        matlab.ui.container.Panel
        RayoncmLabel                   matlab.ui.control.Label
        RayonField                     matlab.ui.control.NumericEditField
        UIActionAxes                   matlab.ui.control.UIAxes
        HauteurcmLabel                 matlab.ui.control.Label
        HauteurActionEditField         matlab.ui.control.NumericEditField
        ActionPlusButton               matlab.ui.control.Button
        ChampsTLabel                   matlab.ui.control.Label
        ActionChampsEditField          matlab.ui.control.NumericEditField
        MassegLabel                    matlab.ui.control.Label
        ActionMasseEditField           matlab.ui.control.NumericEditField
        BalayageTab                    matlab.ui.container.Tab
        UIBalayageTable                matlab.ui.control.Table
        ConfigurationspourbalayageLabel  matlab.ui.control.Label
    end

    
    %% PRIVATE PROPERTIES
    properties (Access = private)
        LameNumPoints;               % Number of points in the array
        APICoinMap = containers.Map; % Map containing all coins coming from the API
        loadConfigPath;              % Path to configuration files in main directory
        loadSimuPath;                
        captHeight = 0.45;         % Macro defining thickness (3D height) of capacitive detector
        configChanged = false;
        
        % Data from Lame Plus UI
        LameE = 0;
        LameJ = 0;
        LameAtt = 0;
        LameWn = 0;
        LameMu = 0; 
        LameRn = 0;
        Lamet = 0;
        Lamed = 0;
        Lameb = 0;
        
        % Data from Action Plus UI
        ActionL = 0;
        ActionMu = 0;
        ActionN = 0;
        Actionl = 0;
        ActionA = 0;
        
        % Data from Capt Plus UI
        CaptKp = 0;
        CaptKi = 0;
        CaptKd = 0;
        
        % Variable to notify when the balayage function is needed
        useBalayage = false;
    end
    %% PUBLIC PROPERTIES
    properties (Access = public)
        LameArray;                   % Array containing all points that make-up the platform (lame flexible)
        stopSim = false;
        
        minCADYear;                  % Minimum year recorded in the Excel file for Canadian coins
        maxCADYear;                  % Maximum year recorded in the Excel file for Canadian coins
        coinCADMap;                  % Map containing all coins coming from the Excel file
        
        lamePlusApp;
        captPlusApp;
        actionPlusApp;
        
        dimensionStruct;             % Structure for data in dimensions panel
        lameflexStruct;              % Structure for data in lame flexible panel
        captStruct;                  % Structure for data in capacitive detector panel
        timeStruct;                  % Structure for data in time constraints panel
        actionStruct;                % Structure for data in linear actuator panel
        simulCellArray;              % Structure for simulation data (events, coins, time)
        coinCellArray;               % Structure for coins added to CAO
        dropDownCoinList;
        balayageCellArray;
        
        balayageEnabled;
        balayageData;
        
        allParametersCellArray;
    end
    
    %% ENABLE/DISABLE UI FOR SIMULATION
    methods (Access = private)
        
        function disableUI(app)
            % Disable numismat table and configurations
            set(app.UICoinTable, 'Enable', 'off');
            set(app.AddPanel.Children, 'Enable', false);
            set(app.APIAddPanel.Children, 'Enable', false);
            set(app.RemPanel.Children, 'Enable', false);
            set(app.ManualCheckBox, 'Enable', false);
            set(app.APICheckBox, 'Enable', false);
            set(app.ActiverbalayageCheckBox, 'Enable', false);
            
            % Disable scale dimension features
            set(app.LongueurBalanceField, 'Enable', false);
            set(app.LargeurBalanceField, 'Enable', false);
            set(app.HauteurBalanceField, 'Enable', false);
            set(app.PositionduplateauSlider, 'Enable', false);
            set(app.GainAmplificateurEditField,'Enable',false);
            
            % Disable lame dimension features
            set(app.EpaisseurLameField, 'Enable', false);
            set(app.MasseLameField, 'Enable', false);
            set(app.LongueurLameField, 'Enable', false);
            set(app.LargeurLameField, 'Enable', false);
            set(app.LamePlusButton,'Enable',false);
            
            % Disable position detector dimension features
            set(app.EspacementmmSlider,'Enable',false);
            set(app.CaptPlusButton,'Enable',false);
            set(app.VimEditField,'Enable',false);
            set(app.VthEditField,'Enable',false);
            
            % Disable event features
            set(app.UIEventTable, 'Enable', 'off');
            set(app.AjoutervenementButton, 'Enable', false);
            set(app.EnelvervenementButton, 'Enable', false);
            set(app.ParcourirLoadSimuButton, 'Enable', false);
            set(app.LoadSimuEditField, 'Enable', false);
            
            % Disable archive features
            set(app.HistoriquedesconfigurationsListBox, 'Enable', false);
            set(app.ParcourirLoadButton, 'Enable', false);
            set(app.LoadEditField, 'Enable', false);
            set(app.AjouterConfigButton, 'Enable', false);
            set(app.ChargerlaslectionButton, 'Enable', false);
            set(app.NettoyerhistoriqueButton, 'Enable', false);
            
            % Disable time features
            set(app.NombredchantillonsEditField, 'Enable', false);
            set(app.TempsparchantillonusEditField, 'Enable', false);
            
            % Disable activator features
            set(app.RayonField, 'Enable', false);
            set(app.HauteurActionEditField,'Enable',false);
            set(app.ActionPlusButton,'Enable',false);
            set(app.ActionChampsEditField,'Enable',false);
            set(app.ActionMasseEditField,'Enable',false);
        end
        
        function enableUI(app)
            % Enable numismat table and configurations
            set(app.UICoinTable, 'Enable', 'on');
            set(app.AddPanel.Children, 'Enable', true);
            set(app.APIAddPanel.Children, 'Enable', true);
            set(app.RemPanel.Children, 'Enable', true);
            set(app.ManualCheckBox, 'Enable', true);
            set(app.APICheckBox, 'Enable', true);
            set(app.ActiverbalayageCheckBox, 'Enable', true);
            
            % Enable scale dimension features
            set(app.LongueurBalanceField, 'Enable', true);
            set(app.LargeurBalanceField, 'Enable', true);
            set(app.HauteurBalanceField, 'Enable', true);
            set(app.PositionduplateauSlider, 'Enable', true);
            set(app.GainAmplificateurEditField,'Enable',true);
            
            % Enable lame dimension features
            set(app.EpaisseurLameField, 'Enable', true);
            set(app.MasseLameField, 'Enable', true);
            set(app.LongueurLameField, 'Enable', true);
            set(app.LargeurLameField, 'Enable', true);
            set(app.LamePlusButton,'Enable',true);
            
            % Enable position detector dimension features
            set(app.EspacementmmSlider,'Enable',true);
            set(app.CaptPlusButton,'Enable',true);
            set(app.VimEditField,'Enable',true);
            set(app.VthEditField,'Enable',true);
            
            % Enable event features
            set(app.UIEventTable, 'Enable', 'on');
            set(app.AjoutervenementButton, 'Enable', true);
            set(app.EnelvervenementButton, 'Enable', true);
            set(app.ParcourirLoadSimuButton, 'Enable', true);
            set(app.LoadSimuEditField, 'Enable', true);
            
            % Enable archive features
            set(app.HistoriquedesconfigurationsListBox, 'Enable', true);
            set(app.ParcourirLoadButton, 'Enable', true);
            set(app.LoadEditField, 'Enable', true);
            set(app.AjouterConfigButton, 'Enable', true);
            set(app.ChargerlaslectionButton, 'Enable', true);
            set(app.NettoyerhistoriqueButton, 'Enable', true);
            
            % Enable time features
            set(app.NombredchantillonsEditField, 'Enable', true);
            set(app.TempsparchantillonusEditField, 'Enable', true);
            
            % Enable activator features
            set(app.RayonField, 'Enable', true);
            set(app.HauteurActionEditField,'Enable',true);
            set(app.ActionPlusButton,'Enable',true);
            set(app.ActionChampsEditField,'Enable',true);
            set(app.ActionMasseEditField,'Enable',true);
        end
        
        function packConfig(app)
            % Construct all strctures pertaining to the configuration Tab
            app.dimensionStruct = struct('length',app.LongueurBalanceField.Value/100, ...
                                            'width',app.LargeurBalanceField.Value/100, ...
                                            'height',app.HauteurBalanceField.Value/100, ...
                                            'pos_act',app.PositionduplateauSlider.Value/100, ...
                                            'pos_limits',app.PositionduplateauSlider.Limits/100, ...
                                            'height_limits',app.HauteurBalanceField.Limits/100, ...
                                            'gain_ampli',app.GainAmplificateurEditField.Value);
            app.lameflexStruct = struct('length',app.LongueurLameField.Value/100, ...
                                            'width',app.LargeurLameField.Value/100, ...
                                            'thick',app.EpaisseurLameField.Value/1000, ...
                                            'mass',app.MasseLameField.Value/1000, ...
                                            'E',app.LameE, ...
                                            'J',app.LameJ, ...
                                            'wn',app.LameWn, ...
                                            'Att',app.LameAtt, ...
                                            'mu',app.LameMu);
            app.captStruct = struct('spacing',app.EspacementmmSlider.Value/2000, ...
                                            'Kp',app.CaptKp, ...
                                            'Ki',app.CaptKi, ...
                                            'Kd',app.CaptKd, ...
                                            'Vim',app.VimEditField.Value, ...
                                            'Vth',app.VthEditField.Value);
            app.timeStruct = struct('num_samples',app.NombredchantillonsEditField.Value, ...
                                            'duration',app.TempsparchantillonusEditField.Value/1000000);
            app.actionStruct = struct('radius',app.RayonField.Value/100, ...
                                            'L',app.ActionL, ...
                                            'N',app.ActionN, ...
                                            'height',app.HauteurActionEditField.Value/100, ...
                                            'm', app.ActionMasseEditField.Value/1000, ...
                                            'B', app.ActionChampsEditField.Value);
            app.simulCellArray = app.UIEventTable.Data;
            app.coinCellArray = app.UICoinTable.Data;
            app.dropDownCoinList = app.LigneDropDown.Items;
            app.balayageCellArray = app.UIBalayageTable.Data;
        end
        
        function packConfigOnClose(app,src,event)
            % 'Cancel' has been selected
            if event.SelectedOptionIndex == 2
                delete(app);
                return;
            end
            
            if event.SelectedOptionIndex == 3
                return;
            end

            app.dimensionStruct = struct('length',app.LongueurBalanceField.Value/100, ...
                                            'width',app.LargeurBalanceField.Value/100, ...
                                            'height',app.HauteurBalanceField.Value/100, ...
                                            'pos_act',app.PositionduplateauSlider.Value/100, ...
                                            'pos_limits',app.PositionduplateauSlider.Limits/100, ...
                                            'height_limits',app.HauteurBalanceField.Limits/100, ...
                                            'gain_ampli',app.GainAmplificateurEditField.Value);
            app.lameflexStruct = struct('length',app.LongueurLameField.Value/100, ...
                                            'width',app.LargeurLameField.Value/100, ...
                                            'thick',app.EpaisseurLameField.Value/1000, ...
                                            'mass',app.MasseLameField.Value/1000, ...
                                            'E',app.LameE, ...
                                            'J',app.LameJ, ...
                                            'wn',app.LameWn, ...
                                            'Att',app.LameAtt, ...
                                            'mu',app.LameMu);
            app.captStruct = struct('spacing',app.EspacementmmSlider.Value/2000, ...
                                            'Kp',app.CaptKp, ...
                                            'Ki',app.CaptKi, ...
                                            'Kd',app.CaptKd, ...
                                            'Vim',app.VimEditField.Value, ...
                                            'Vth',app.VthEditField.Value);
            app.timeStruct = struct('num_samples',app.NombredchantillonsEditField.Value, ...
                                            'duration',app.TempsparchantillonusEditField.Value/1000000);
            app.actionStruct = struct('radius',app.RayonField.Value/100, ...
                                            'L',app.ActionL, ...
                                            'N',app.ActionN, ...
                                            'height',app.HauteurActionEditField.Value/100, ...
                                            'm', app.ActionMasseEditField.Value/1000, ...
                                            'B', app.ActionChampsEditField.Value);
            app.simulCellArray = app.UIEventTable.Data;
            app.coinCellArray = app.UICoinTable.Data;
            app.dropDownCoinList = app.LigneDropDown.Items;
            app.balayageCellArray = app.UIBalayageTable.Data;
            
            % Save the configurations in a file located in .\config
            saveConfig(app);
            
            % Close the app
            delete(app);
        end
    end
    
    %% MANAGE NUMISMAT CONFIGURATIONS
    methods (Access = private)

        function addToLigneDropDown(app)
            % Get all dropdown menu items
            curr_dropdown = get(app.LigneDropDown, 'Items');
            [row, col] = size(curr_dropdown);
            
            cell_array = cell(1,col+1);
            
            for i=1:col
                cell_array(1,i) = curr_dropdown(1,i);
            end
            cell_array(1,end) = {int2str(col+1)};
            
            % Add to column format of UIEventTable
            app.UIEventTable.ColumnFormat = ({[] {'Ajouter' 'Retirer'} cell_array});

            % Add new line to dropdown
            set(app.LigneDropDown, 'Items', cell_array);
        end
        
        function remFromLigneDropDown(app)
            % Get all dropdown menu items
            curr_dropdown = get(app.LigneDropDown, 'Items');
            [row, col] = size(curr_dropdown);
            
            cell_array = cell(1,col-1);
            
            for i=1:col
                if cell2mat(curr_dropdown(1,i)) ~= cell2mat(curr_dropdown(1,end))
                    cell_array(1,i) = curr_dropdown(1,i);
                end
            end
            
            % Add to column format of UIEventTable
            if isempty(cell_array)
                app.UIEventTable.ColumnFormat = ({[] {'Ajouter' 'Retirer'} []});
            else
                app.UIEventTable.ColumnFormat = ({[] {'Ajouter' 'Retirer'} cell_array}); 
            end

            % Set new dropdown with removed line
            set(app.LigneDropDown, 'Items', cell_array);
        end
        
        function addToCoinTable(app,currency,amount,quantity,year,weight)
            % Validate if quantity provided is valid
            if quantity <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Quantité de pièces invalide",'Erreur',"Icon",'error');
                return
            end
            
            % Validate if year specified in query is valid
            if currency == "CAD"
                if isinteger(year)
                    if year < app.minCADYear
                        uialert(app.SimulateurdebalanceUIFigure,"Année invalide (minimum = " + int2str(app.minCADYear) + ")",'Erreur',"Icon",'error');
                        return
                    elseif year > app.maxCADYear
                        uialert(app.SimulateurdebalanceUIFigure,"Année invalide (maximum = " + int2str(app.maxCADYear) + ")",'Erreur',"Icon",'error');
                        return
                    end
                end
            end
            
            if weight <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Masse invalide (nulle ou plus petite que 0)",'Erreur',"Icon",'error');
                return
            end

            % Populate CoinTable cell array and add new row
            curr_data = get(app.UICoinTable, 'Data');
            [row, col] = size(curr_data);
            
            cell_array = cell(row+1,5);
            
            for i=1:size(curr_data,1)
                cell_array(i,:) = curr_data(i,:);
            end
            cell_array(end,:) = {currency, amount, quantity, year, weight};
 
            % Add new set of data to table
            set(app.UICoinTable, 'Data', cell_array);
            
            % Add to dropdown list for lines in table
            addToLigneDropDown(app);
        end
        
        function remFromCoinTable(app,line)
            % Populate CoinTable cell array and add new row
            curr_data = get(app.UICoinTable, 'Data');
            [row, col] = size(curr_data);
            
            if row == 0
                uialert(app.SimulateurdebalanceUIFigure,"Aucun élément dans la table",'Erreur',"Icon",'error');
                return
            end
            
            cell_array = cell(row-1,5);
            index = line-'0';
            
            for i=1:size(curr_data,1)
                if i < size(curr_data,1)
                   if i >= index
                       cell_array(i,:) = curr_data(i+1,:);
                   else
                       cell_array(i,:) = curr_data(i,:);
                   end
                end
            end
 
            % Add new set of data to table
            set(app.UICoinTable, 'Data', cell_array);
            
            % Add to dropdown list for lines in table
            remFromLigneDropDown(app);
        end
    end
    
    %% MANAGE DIMENSIONS CONFIGURATIONS
    methods (Access = private)
              
        function initUIValues(app)
            % Configurations for nimusmats
            set(app.ManualCheckBox,'Value',true);
            set(app.APIAddPanel.Children,'Enable',false);
            
            % Deactivate balayage
            set(app.ActiverbalayageCheckBox,'Value',false);
            set(app.UIBalayageTable,'Enable','off');
            
            % Initialize UIEventTable's column format
            app.UIEventTable.Data = {0 'Ajouter' 'Aucune'};
            app.UIEventTable.ColumnFormat = ({[] {'Ajouter' 'Retirer'} []});
            
            % Load default values of UI
            loadDefault(app,"default.mat");
            setDefaultConfig(app);
            
            % Initialize an empty array with said number of points
            app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
            
            % Reset configuration UI flag
            app.configChanged = false;
            
            % Initialize UIBalayageTable's column format
            app.UIBalayageTable.Data = {'Aucun' 0 0 0};
            app.UIBalayageTable.ColumnFormat = ({app.allParametersCellArray [] [] []});
        end
    end
    
    %% CONFIG FUNCTIONS
    methods (Access = private)
        
        function setDefaultConfig(app)
            % Set new values to scale dimension panel
            app.LongueurBalanceField.Value = app.dimensionStruct.length*100;
            app.LargeurBalanceField.Value = app.dimensionStruct.width*100;
            app.HauteurBalanceField.Value = app.dimensionStruct.height*100;
            app.PositionduplateauSlider.Value = app.dimensionStruct.pos_act*100;
            app.PositionduplateauSlider.Limits = app.dimensionStruct.pos_limits*100;
            app.HauteurBalanceField.Limits = app.dimensionStruct.height_limits*100;
            app.GainAmplificateurEditField.Value = app.dimensionStruct.gain_ampli;
            
            % Set new values to flexible lame panel
            app.LongueurLameField.Value = app.lameflexStruct.length*100;
            app.LargeurLameField.Value = app.lameflexStruct.width*100;
            app.EpaisseurLameField.Value = app.lameflexStruct.thick*1000;
            app.MasseLameField.Value = app.lameflexStruct.mass*1000;
            app.LameE = app.lameflexStruct.E;
            app.LameJ = app.lameflexStruct.J;
            app.LameAtt = app.lameflexStruct.Att;
            app.LameWn = app.lameflexStruct.wn;
            app.LameMu = app.lameflexStruct.mu;
            
            % Set new values to capacitive position detector panel
            app.EspacementmmSlider.Value = app.captStruct.spacing*2*1000;
            app.CaptKp = app.captStruct.Kp;
            app.CaptKi = app.captStruct.Ki;
            app.CaptKd = app.captStruct.Kd;
            app.VimEditField.Value = app.captStruct.Vim;
            app.VthEditField.Value = app.captStruct.Vth;
            
            % Set new values to time constraints panel
            app.NombredchantillonsEditField.Value = app.timeStruct.num_samples;
            app.TempsparchantillonusEditField.Value = app.timeStruct.duration*1000000;
            
            % Set new values to linear actuator
            app.RayonField.Value = app.actionStruct.radius*100;
            app.HauteurActionEditField.Value = app.actionStruct.height*100;
            app.ActionN = app.actionStruct.N;
            app.ActionL = app.actionStruct.L;
            app.ActionChampsEditField.Value = app.actionStruct.B;
            app.ActionMasseEditField.Value = app.actionStruct.m*1000;
        end
        
        function setNewConfig(app)
            % Set new values to scale dimension panel
            app.LongueurBalanceField.Value = app.dimensionStruct.length*100;
            app.LargeurBalanceField.Value = app.dimensionStruct.width*100;
            app.HauteurBalanceField.Value = app.dimensionStruct.height*100;
            app.PositionduplateauSlider.Value = app.dimensionStruct.pos_act*100;
            app.PositionduplateauSlider.Limits = app.dimensionStruct.pos_limits*100;
            app.HauteurBalanceField.Limits = app.dimensionStruct.height_limits*100;
            app.GainAmplificateurEditField.Value = app.dimensionStruct.gain_ampli;
            
            % Set new values to flexible lame panel
            app.LongueurLameField.Value = app.lameflexStruct.length*100;
            app.LargeurLameField.Value = app.lameflexStruct.width*100;
            app.EpaisseurLameField.Value = app.lameflexStruct.thick*1000;
            app.MasseLameField.Value = app.lameflexStruct.mass*1000;
            app.LameE = app.lameflexStruct.E;
            app.LameJ = app.lameflexStruct.J;
            app.LameAtt = app.lameflexStruct.Att;
            app.LameWn = app.lameflexStruct.wn;
            app.LameMu = app.lameflexStruct.mu;
            
            % Set new values to capacitive position detector panel
            app.EspacementmmSlider.Value = app.captStruct.spacing*2*1000;
            app.CaptKp = app.captStruct.Kp;
            app.CaptKi = app.captStruct.Ki;
            app.CaptKd = app.captStruct.Kd;
            app.VimEditField.Value = app.captStruct.Vim;
            app.VthEditField.Value = app.captStruct.Vth;
            
            % Set new values to time constraints panel
            app.NombredchantillonsEditField.Value = app.timeStruct.num_samples;
            app.TempsparchantillonusEditField.Value = app.timeStruct.duration*1000000;
            
            % Set new values to linear actuator
            app.RayonField.Value = app.actionStruct.radius*100;
            app.HauteurActionEditField.Value = app.actionStruct.height*100;
            app.ActionN = app.actionStruct.N;
            app.ActionL = app.actionStruct.L;
            app.ActionChampsEditField.Value = app.actionStruct.B;
            app.ActionMasseEditField.Value = app.actionStruct.m*1000;
            
            % Set new values to event table
            app.UIEventTable.Data = app.simulCellArray; 
            app.LigneDropDown.Items = app.dropDownCoinList;
            
            % Set new numismat values
            app.UICoinTable.Data = app.coinCellArray;
            
            % Set new values in balayage cell array
            app.UIBalayageTable.Data = app.balayageCellArray;
        end
        
        function removeAllConfig(app,src,event)
            % 'Cancel' has been selected
            if event.SelectedOptionIndex == 2
                return
            end
            app.HistoriquedesconfigurationsListBox.Items = {};
            delete("config\*");
        end
        
        function loadSelectedConfig(app)
            fileSelected = app.HistoriquedesconfigurationsListBox.Value;
            if isempty(fileSelected)
                return
            end
            loadConfig(app,fileSelected);
            setNewConfig(app);
            
            % Reset configuration UI flag
            app.configChanged = false;
                
            % Update animation on configuration side
            plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                app.LameArray,app.LongueurLameField.Value, ...
                app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
            
            % Plot capacitive position detector spacing graph
            plotCapt(app.UICaptAxes,app.LongueurBalanceField.Value/4,app.captHeight,app.LargeurBalanceField.Value,app.EspacementmmSlider.Value);
            
            % Plot actuator coil in 3D
            plotAction(app.UIActionAxes,0,0,app.RayonField.Value);
            
            % Plot lame in 3D
            plotLameDim(app.UILameAxes,app.LongueurLameField.Value,app.LargeurLameField.Value,app.EpaisseurLameField.Value);
            
            close all
        end
    end
    
    methods (Access = public)
        function loadSimulation(app,path)
            simulationTable = readtable(path);
            moments = table2array(simulationTable(:,'Moment'));
            actions = table2array(simulationTable(:,'Action'));
            masses = table2array(simulationTable(:,'Masse'));
            years = table2array(simulationTable(:,'Ann_e'));
            quantities = table2array(simulationTable(:,'Quantit_'));
            currencies = table2array(simulationTable(:,'Devise'));
            amounts = table2array(simulationTable(:,'Montant'));
            
            % Add new empty set of data to table
            cell_array = cell(length(moments),3);
                
            for index=1:length(moments)
                addToCoinTable(app,cell2mat(currencies(index)),cell2mat(amounts(index)),quantities(index),cell2mat(years(index)),masses(index));                
                cell_array(index,:) = {moments(index), cell2mat(actions(index)), index};
            end
            
            % Add new set of data to table
            set(app.UIEventTable,'Data',cell_array);
            
            % Notify UI changes
            app.configChanged = true;
        end
        
        function saveLamePlusMethod(app,E,J,Att,wn,mu,t,d,b,m)
            app.LameE = E;
            app.LameJ = J;
            app.LameAtt = Att;
            app.configChanged = true;
            app.LameWn = wn;
            app.LameMu = mu;
            app.LargeurLameField.Value = t;
            app.EpaisseurLameField.Value = d;
            app.Lameb = b;
            app.MasseLameField.Value = m;
        end
        
        function saveActionPlusMethod(app,L,l,mu,N,A)
            app.ActionL = L;
            app.ActionMu = mu;
            app.ActionN = N;
            app.RayonField.Value = sqrt(A/pi());
            app.HauteurActionEditField.Value = l;
            app.configChanged = true;
        end

        function saveCaptPlusMethod(app,kp,ki,kd)
            app.CaptKp = kp;
            app.CaptKi = ki;
            app.CaptKd = kd;
            app.configChanged = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
            % Add untracked paths
            addpath('plotFunc');
            addpath('logFunc');
            addpath('UIs');
            addpath("UIs\pics\");
            addpath('archiveFunc');
            addpath('default');
            
            % Make all default values available to UI
            make_default();
            
            % Setup data from small database (future updates might include link to ANS API)
            setup();
            
            % Initialize all data that refer to the mainCAO UI
            initUIValues(app);
            
            % Load all saved configurations from previous runs
            loadAllConfigNames(app);
            
            % Initially plot the base of the scale
            plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                app.LameArray,app.LongueurLameField.Value, ...
                app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
            
            close all % Close remaining figures that pop-up for no reason
        end

        % Button pushed function: AmorcerlasimulationButton
        function AmorcerlasimulationButtonPushed(app, event)
            if (app.AmorcerlasimulationButton.Text == "Arreter l'animation") || (app.AmorcerlasimulationButton.Text == "En attente...")
                app.Lamp.Color = 'green';
                app.AmorcerlasimulationButton.Text = "Amorcer la simulation";
                
                % Remotely stop simulation
                app.stopSim = true;
                enableUI(app);
                
                % Reset array for lame
                app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
                
                % Plot animation on simulation side
                plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
                close all
                return;
            elseif app.AmorcerlasimulationButton.Text == "Amorcer la simulation"
                % Change UI assets for simulation
                app.stopSim = false;
                
                % Disable different components in UI during runtime
                disableUI(app);
                
                % Fetch all configurations from app
                packConfig(app);
                
                app.Lamp.Color = 'blue';
                app.AmorcerlasimulationButton.Text = "En attente...";
                
                % Set values for balayage functions
                app.balayageData = get(app.UIBalayageTable,'Data');
                app.balayageEnabled = app.ActiverbalayageCheckBox.Value;
                
                % Simulate with current configurations
                [ret, msg] = simulation(app);
                if ret ~= 1
                    uialert(app.SimulateurdebalanceUIFigure,msg,'Erreur',"Icon",'error');
                    app.Lamp.Color = 'green';
                    app.AmorcerlasimulationButton.Text = "Amorcer la simulation";
                    enableUI(app);
                    
                    % Reset array for lame
                    app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
                    
                    % Plot animation on simulation side
                    plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
                    close all
                    return;
                end
                app.Lamp.Color = 'green';
                app.AmorcerlasimulationButton.Text = "Amorcer la simulation";
                enableUI(app);
                
                % Reset array for lame
                app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
                
                % Plot animation on simulation side
                plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                app.LameArray,app.LongueurLameField.Value, ...
                app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
                close all
            end
        end

        % Button pushed function: AjouterButton
        function AjouterButtonPushed(app, event)
            if app.AnneEditField.Value < app.minCADYear
                uialert(app.SimulateurdebalanceUIFigure,"Année non spécifiée ou invalide",'Erreur',"Icon",'error');
                return;
            end
            if app.QuantitEditField.Value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Quantité de pièces invalide",'Erreur',"Icon",'error');
                return;
            end
            switch (app.MontantDropDown.Value)
                case '1¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x1cent;
                case '5¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x5cent;
                case '10¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x10cent;
                case '20¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x20cent;
                case '25¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x25cent;
                case '50¢'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x50cent;
                case '1$'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x1dol;
                case '2$'
                    weight = app.coinCADMap(num2str(app.AnneEditField.Value)).x2dol;
            end
            addToCoinTable(app,app.DeviseDropDown.Value,app.MontantDropDown.Value,app.QuantitEditField.Value,app.AnneEditField.Value,weight);
        end

        % Button pushed function: RetirerButton
        function RetirerButtonPushed(app, event)
            remFromCoinTable(app,app.LigneDropDown.Value);
        end

        % Value changing function: PositionduplateauSlider
        function PositionduplateauSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue < 0
                uialert(app.SimulateurdebalanceUIFigure,"Position de l'actionneur (et du plateau) invalide",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Set new values in plots
            plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    changingValue,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
            close all % Close remaining figures that pop-up for no reason
        end

        % Button pushed function: ParcourirLoadButton
        function ParcourirLoadButtonPushed(app, event)
            % Browse computer directory
            [file,path] = uigetfile();
            
            % Cancel has been pressed
            if file == 0
                return
            end
                
            % Save directory on UI and in back-end
            loadConfig(app,file);
            app.loadConfigPath = path;
            
            % Set the newly obtained configuration data
            setNewConfig(app);
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            value = app.ManualCheckBox.Value;
            if value == true
                % Set values for API interaction to false
                set(app.APICheckBox,'Value',false);
                set(app.APIAddPanel.Children,'Enable',false);
                set(app.AddPanel.Children,'Enable',true);
                
                % Disable the Excel checkbox
                set(app.ManualCheckBox, 'Enable', false);
                
                % Reenable the API checkbox
                set(app.APICheckBox, 'Enable', true);
            end
        end

        % Value changed function: APICheckBox
        function APICheckBoxValueChanged(app, event)
            value = app.APICheckBox.Value;
            if value == true
                % Set values for Excel file interaction to false
                set(app.ManualCheckBox,'Value',false);
                set(app.APIAddPanel.Children,'Enable',true);
                set(app.AddPanel.Children,'Enable',false);
                
                % Disable the API checkbox
                set(app.APICheckBox, 'Enable', false);
                
                % Reenable the Excel checkbox
                set(app.ManualCheckBox, 'Enable', true);
            end
        end

        % Button pushed function: APIAjouterButton
        function APIAjouterButtonPushed(app, event)
            % Get API identifier from query in UI
            identifier = app.APIEditField_1.Value + "." + app.APIEditField_2.Value + "." + app.APIEditField_3.Value;
            
            % Send query to API and return data corresponding with query
            [title,region,weight,year] = getCoinById(app,identifier);
                
            % If none of the fields have been updated to a valid value
            if (title == "") && (region == "") && (weight == 0) && (year == "")
                uialert(app.SimulateurdebalanceUIFigure,"Le code spécifié est incomplet ou invalide",'Erreur',"Icon",'error');
                return
            end
            
            % Add response to API coin map
            app.APICoinMap(identifier) = struct('title',title,'region',region,'weight',weight,'year',year);
            
            % Add to coin table
            addToCoinTable(app,char(region),'Inconnu',1,char(year),weight);
        end

        % Button pushed function: urlNumismatButton
        function urlNumismatButtonPushed(app, event)
            % Open the database website
            dos('explorer http://numismatics.org/search/results');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Switch from config tab to simulation tab
            if selectedTab == app.SimulationTab
                % Reset lame array
                app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
                
                % Plot animation on simulation side
                plotBase(app.UIAnim,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
                close all
            % Switch from simulation tab to config tab
            elseif selectedTab == app.ConfigurationTab
                % Reset lame array
                app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
                
                % Plot animation on configurations side
                plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
                % Plot capacitive position detector spacing graph
                plotCapt(app.UICaptAxes,app.LongueurBalanceField.Value/4,app.captHeight,app.LargeurLameField.Value,app.EspacementmmSlider.Value);
                % Plot actuator coil in 3D
                plotAction(app.UIActionAxes,0,0,app.RayonField.Value);
                % Plot lame in 3D
                plotLameDim(app.UILameAxes,app.LongueurLameField.Value,app.LargeurLameField.Value,app.EpaisseurLameField.Value);
                close all
            end
        end

        % Value changing function: EspacementmmSlider
        function EspacementmmSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Espacement entre les électrodes invalide",'Erreur',"Icon",'error');
                return
            end
            
            % Reset lame array
            app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
            
            % Plot 3D model of capacitive position detector
            plotCapt(app.UICaptAxes,app.LongueurBalanceField.Value/4,app.captHeight,app.LargeurLameField.Value,changingValue);
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Update animation on configuration side
            plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,changingValue/10);
            close all
        end

        % Close request function: SimulateurdebalanceUIFigure
        function SimulateurdebalanceUIFigureCloseRequest(app, event)
            if app.configChanged
                uiconfirm(app.SimulateurdebalanceUIFigure,...
                    "Voulez-vous sauvegarder les configurations actuelles?",...
                    'Fermeture','Options',{'Sauvegarder','Ne pas sauvegarder','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3,'CloseFcn',@(src,event)packConfigOnClose(app,src,event));
            else
                % Close the apps
                delete(app.lamePlusApp);
                delete(app.captPlusApp);
                delete(app.actionPlusApp);
                delete(app);
            end
        end

        % Value changed function: LongueurLameField
        function LongueurLameFieldValueChanged(app, event)
            value = app.LongueurLameField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Longueur de la lame trop petite, nulle ou négative",'Erreur',"Icon",'error');
                return
            end
            
            % Set scale length value and limits
            app.LongueurBalanceField.Value = value;
            set(app.PositionduplateauSlider,'Limits',[0.05*app.LongueurLameField.Value,...
                app.LongueurLameField.Value-4*app.RayonField.Value-app.LongueurLameField.Value/4]);
            
            % Modify limits on Lame axes
            set(app.UILameAxes,'YLim',[0,value]);
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Reset lame array
            app.LameArray = zeros(1,(round(app.LongueurLameField.Value)-1)+1);
            
            % Plot lame in respective UI
            plotLameDim(app.UILameAxes,app.LongueurLameField.Value,app.LargeurLameField.Value,app.EpaisseurLameField.Value);
            
            % Update animation on configuration side
            plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
            close all
        end

        % Button pushed function: NettoyerhistoriqueButton
        function NettoyerhistoriqueButtonPushed(app, event)
            uiconfirm(app.SimulateurdebalanceUIFigure,...
                "Êtes-vous certain de vouloir supprimer l'historique des configurations?",...
                'Confirmation','CloseFcn',@(src,event)removeAllConfig(app,src,event));
        end

        % Button pushed function: AjouterConfigButton
        function AjouterConfigButtonPushed(app, event)
            if app.configChanged
                app.configChanged = false;
                
                % Build all structs
                packConfig(app);
                
                % Save the configurations in a file located in .\config
                saveConfig(app);
                
                % Add configurations to the list box
                app.HistoriquedesconfigurationsListBox.Items = {};
                loadAllConfigNames(app)
            else
                uialert(app.SimulateurdebalanceUIFigure,"La configuration est déjà enregistré",'Attention',"Icon",'warning');
            end
        end

        % Value changed function: RayonField
        function RayonFieldValueChanged(app, event)
            value = app.RayonField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Rayon invalide (nul ou négatif)",'Erreur',"Icon",'error');
                return
            elseif value > app.LongueurLameField.Value*0.75-app.RayonField.Value*3
                uialert(app.SimulateurdebalanceUIFigure,"Rayon invalide (trop grand)",'Erreur',"Icon",'error');
                app.RayonField.Value = 2;
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Set new limits for position slider
            set(app.PositionduplateauSlider,'Limits',[0.15*app.HauteurBalanceField.Value,...
                app.LongueurLameField.Value-4*app.RayonField.Value-app.LongueurBalanceField.Value/4]);
            
            % Plot 3D Actuator
            plotAction(app.UIActionAxes,0,0,app.RayonField.Value);
            
            % Plot animation on configurations side
            plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                    app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                    app.LameArray,app.LongueurLameField.Value, ...
                    app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
            close all
        end

        % Value changed function: LargeurLameField
        function LargeurLameFieldValueChanged(app, event)
            value = app.LargeurLameField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Largeur de la lame trop petite",'Erreur',"Icon",'error');
                return
            end
            
            if value > app.UILameAxes.XLim(2)
                uialert(app.SimulateurdebalanceUIFigure,"Largeur de la lame trop grande",'Erreur',"Icon",'error');
                return
            end
            
            app.LargeurBalanceField.Value = value;
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Plot lame in respective UI
            plotLameDim(app.UILameAxes,app.LongueurLameField.Value,app.LargeurLameField.Value,app.EpaisseurLameField.Value);
            close all
        end

        % Value changed function: EpaisseurLameField
        function EpaisseurLameFieldValueChanged(app, event)
            value = app.EpaisseurLameField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Épaisseur de lame invalide (nulle ou plus petite que 0)",'Erreur',"Icon",'error');
                return
            end
            
            % Set new limits to spacing between electrodes
            app.EspacementmmSlider.Limits = [value, value+4];
            if app.EspacementmmSlider.Value < value
                app.EspacementmmSlider.Value = value;
            end
            
            % Modify limits on Lame axes
            set(app.UICaptAxes,'ZLim',[0,value+5]);
            
            app.captHeight = value/4.5;
            
            % Plot 3D model of capacitive position detector
            plotCapt(app.UICaptAxes,app.LongueurBalanceField.Value/4,app.captHeight,app.LargeurLameField.Value,app.EspacementmmSlider.Value);
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Plot lame in respective UI
            plotLameDim(app.UILameAxes,app.LongueurLameField.Value,app.LargeurLameField.Value,app.EpaisseurLameField.Value);
            close all
        end

        % Button pushed function: ChargerlaslectionButton
        function ChargerlaslectionButtonPushed(app, event)
            if ~isempty(app.HistoriquedesconfigurationsListBox.Items)
                uiconfirm(app.SimulateurdebalanceUIFigure,...
                    "Êtes-vous certain de vouloir charger la configuration sélectionnée?",...
                    'Confirmation','CloseFcn',@(src,event)loadSelectedConfig(app));
            else
                uialert(app.SimulateurdebalanceUIFigure,"Aucune configuration de disponible",'Erreur',"Icon",'error');
            end
        end

        % Button pushed function: AjoutervenementButton
        function AjoutervenementButtonPushed(app, event)
            curr_data = get(app.UIEventTable,'Data');
            [row, col] = size(curr_data);
            
            % Add new empty set of data to table
            cell_array = cell(row+1,3);
            
            for i=1:size(curr_data,1)
                cell_array(i,:) = curr_data(i,:);
            end
            cell_array(end,:) = {0, 'Ajouter', 'Aucune'};
 
            % Add new set of data to table
            set(app.UIEventTable,'Data',cell_array);
        end

        % Button pushed function: EnelvervenementButton
        function EnelvervenementButtonPushed(app, event)
            curr_data = get(app.UIEventTable,'Data');
            [row, col] = size(curr_data);
            
            if row == 1
                return;
            end
            
            % Add new empty set of data to table
            cell_array = cell(row-1,3);
            
            for i=1:size(curr_data,1)-1
                cell_array(i,:) = curr_data(i,:);
            end
            
            % Add new set of data to table
            set(app.UIEventTable,'Data',cell_array);
        end

        % Button pushed function: ParcourirLoadSimuButton
        function ParcourirLoadSimuButtonPushed(app, event)
            % Browse computer directory
            [file,path] = uigetfile();
            
            % Cancel has been pressed
            if file == 0
                return
            end
                
            % Save directory on UI and in back-end
            newpath = append(path,file);
            loadSimulation(app,newpath);
            app.loadSimuPath = newpath;
            
            % Add new path to the edit field
            set(app.LoadSimuEditField,'Value',newpath);
        end

        % Value changed function: NombredchantillonsEditField
        function NombredchantillonsEditFieldValueChanged(app, event)
            value = app.NombredchantillonsEditField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Nombre d'échantillon invalide (nulle ou plus petite que 0)",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Value changed function: TempsparchantillonusEditField
        function TempsparchantillonusEditFieldValueChanged(app, event)
            value = app.TempsparchantillonusEditField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Temps par échantillon invalide (nulle ou plus petit que 0)",'Erreur',"Icon",'error');
                return
            end
%             event.PreviousValue;
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Cell edit callback: UIEventTable
        function UIEventTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Button pushed function: LamePlusButton
        function LamePlusButtonPushed(app, event)
            disableUI(app);
            app.lamePlusApp = lamePlus(app,app.LameE,app.LongueurLameField.Value,app.LameMu, ...
                app.LameRn,app.LameWn,app.LameJ,app.Lamet,app.Lamed,app.LameAtt, ...
                app.MasseLameField.Value,app.Lameb);
            uiwait(app.lamePlusApp.UIFigure);
            enableUI(app);
        end

        % Button pushed function: ActionPlusButton
        function ActionPlusButtonPushed(app, event)
            disableUI(app);
            app.actionPlusApp = actionPlus(app, app.ActionL, app.ActionMu, app.ActionN, app.Actionl/app.ActionN, pi()*((app.RayonField.Value)^2));
            uiwait(app.actionPlusApp.UIFigure);
            enableUI(app);
        end

        % Button pushed function: CaptPlusButton
        function CaptPlusButtonPushed(app, event)
            disableUI(app);
            app.captPlusApp = captPlus(app, app.CaptKp, app.CaptKi, app.CaptKd);
            uiwait(app.captPlusApp.UIFigure);
            enableUI(app);
        end

        % Value changed function: ActiverbalayageCheckBox
        function ActiverbalayageCheckBoxValueChanged(app, event)
            value = app.ActiverbalayageCheckBox.Value;
            app.useBalayage = value;
            if value == true
                app.UIBalayageTable.Enable = 'on';
            else
                app.UIBalayageTable.Enable = 'off';
            end
        end

        % Cell edit callback: UIBalayageTable
        function UIBalayageTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            curr_data = get(app.UIBalayageTable,'Data');
            [row, col] = size(curr_data);
            
            if indices(2) == 1
                if (length(newData) == length('Aucun'))                    
                    if row == 1
                        return;
                    end
                    
                    % Remove empty set of data from table
                    cell_array = cell(row-1,4);
                    
                    for i=1:size(curr_data,1)-1
                        if i >= indices(1)
                            if i < size(curr_data,1)-1
                                cell_array(i,:) = curr_data(i+1,:);
                            end
                        else
                            cell_array(i,:) = curr_data(i,:);
                        end
                    end
                    
                    cell_array(end,:) = {'Aucun', 0, 0, 0};
                    
                    % Add new set of data to table
                    set(app.UIBalayageTable,'Data',cell_array);
                    
                    % Notify app of new changes in config UI
                    app.configChanged = true;
                else
                    if indices(1) == row
                        % Add new empty set of data to table
                        cell_array = cell(row+1,4);
                        
                        for i=1:size(curr_data,1)
                            cell_array(i,:) = curr_data(i,:);
                        end
                        cell_array(end,:) = {'Aucun', 0, 0, 0};
             
                        % Add new set of data to table
                        set(app.UIBalayageTable,'Data',cell_array);
                        
                        % Notify app of new changes in config UI
                        app.configChanged = true;
                    end
                end
            end
        end

        % Value changed function: MasseLameField
        function MasseLameFieldValueChanged(app, event)
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Value changed function: HauteurActionEditField
        function HauteurActionEditFieldValueChanged(app, event)
            value = app.HauteurActionEditField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Temps par échantillon invalide (nulle ou plus petit que 0)",'Erreur',"Icon",'error');
                return
            end
            
            % Set new scale height
            app.HauteurBalanceField.Limits = [0,1.1*value];
            app.HauteurBalanceField.Value = 1.1*value;
            
            app.UIDimension.YLim = [0, 1.3*value];
            app.UIAnim.YLim = [0, 1.3*value];
            
            % Notify app of new changes in config UI
            app.configChanged = true;
            
            % Plot animation on configurations side
            plotDimension(app.UIDimension,app.LongueurBalanceField.Value,app.HauteurBalanceField.Value, ...
                app.PositionduplateauSlider.Value,app.RayonField.Value, ...
                app.LameArray,app.LongueurLameField.Value, ...
                app.LongueurBalanceField.Value/4,app.EspacementmmSlider.Value/10);
            close all
        end

        % Value changed function: VimEditField
        function VimEditFieldValueChanged(app, event)
            value = app.VimEditField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Tension d'entrée maximale trop petite ou nulle",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Value changed function: VthEditField
        function VthEditFieldValueChanged(app, event)
            value = app.VthEditField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Tension threashold trop petite ou nulle",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Value changed function: GainAmplificateurEditField
        function GainAmplificateurEditFieldValueChanged(app, event)
            value = app.GainAmplificateurEditField.Value;
            if value == 0
                uialert(app.SimulateurdebalanceUIFigure,"Gain de l'amplificateur nul",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Value changed function: ActionChampsEditField
        function ActionChampsEditFieldValueChanged(app, event)
            value = app.ActionChampsEditField.Value;
            if value == 0
                uialert(app.SimulateurdebalanceUIFigure,"Champs magnétique de la bobine nul",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end

        % Value changed function: ActionMasseEditField
        function ActionMasseEditFieldValueChanged(app, event)
            value = app.ActionMasseEditField.Value;
            if value <= 0
                uialert(app.SimulateurdebalanceUIFigure,"Masse de l'actionneur linéaire nulle ou négative",'Erreur',"Icon",'error');
                return
            end
            
            % Notify app of new changes in config UI
            app.configChanged = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulateurdebalanceUIFigure = uifigure('Visible', 'off');
            app.SimulateurdebalanceUIFigure.Name = 'Simulateur de balance';
            app.SimulateurdebalanceUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulateurdebalanceUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SimulateurdebalanceUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAnim = uiaxes(app.SimulationTab);
            title(app.UIAnim, '')
            xlabel(app.UIAnim, '')
            ylabel(app.UIAnim, '')
            app.UIAnim.XTick = [];
            app.UIAnim.XTickLabel = '';
            app.UIAnim.YTick = [];
            app.Lamp = uilamp(app.SimulationTab);
            app.ParamtresnumismatiquesLabel = uilabel(app.SimulationTab);
            app.ParamtresnumismatiquesLabel.Text = 'Paramètres numismatiques';
            app.UICoinTable = uitable(app.SimulationTab);
            app.UICoinTable.ColumnName = {'Devise'; 'Montant'; 'Quantité'; 'Année'; 'Poids (g)'};
            app.UICoinTable.RowName = {};
            app.UICoinTable.ColumnEditable = false;
            app.AmorcerlasimulationButton = uibutton(app.SimulationTab, 'push');
            app.AmorcerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @AmorcerlasimulationButtonPushed, true);
            app.AmorcerlasimulationButton.Text = 'Amorcer la simulation';
            app.AddPanel = uipanel(app.SimulationTab);
            app.AjouterButton = uibutton(app.AddPanel, 'push');
            app.AjouterButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterButtonPushed, true);
            app.AjouterButton.Text = 'Ajouter';
            app.DeviseDropDown_2Label = uilabel(app.AddPanel);
            app.DeviseDropDown_2Label.Text = 'Devise';
            app.DeviseDropDown = uidropdown(app.AddPanel);
            app.DeviseDropDown.Items = {'CAD', 'USD'};
            app.DeviseDropDown.Value = 'CAD';
            app.MontantLabel = uilabel(app.AddPanel);
            app.MontantLabel.Text = 'Montant';
            app.MontantDropDown = uidropdown(app.AddPanel);
            app.MontantDropDown.Items = {'1¢', '5¢', '10¢', '20¢', '25¢', '50¢', '1$', '2$'};
            app.MontantDropDown.Value = '1¢';
            app.QuantitEditField_2Label = uilabel(app.AddPanel);
            app.QuantitEditField_2Label.Text = 'Quantité';
            app.QuantitEditField = uieditfield(app.AddPanel, 'numeric');
            app.AnneEditFieldLabel = uilabel(app.AddPanel);
            app.AnneEditFieldLabel.Text = 'Année';
            app.AnneEditField = uieditfield(app.AddPanel, 'numeric');
            app.RemPanel = uipanel(app.SimulationTab);
            app.RetirerButton = uibutton(app.RemPanel, 'push');
            app.RetirerButton.ButtonPushedFcn = createCallbackFcn(app, @RetirerButtonPushed, true);
            app.RetirerButton.Text = 'Retirer';
            app.LigneDropDownLabel = uilabel(app.RemPanel);
            app.LigneDropDownLabel.Text = 'Ligne:';
            app.LigneDropDown = uidropdown(app.RemPanel);
            app.LigneDropDown.Items = {};
            app.LigneDropDown.Value = {};
            app.APIAddPanel = uipanel(app.SimulationTab);
            app.APIAjouterButton = uibutton(app.APIAddPanel, 'push');
            app.APIAjouterButton.ButtonPushedFcn = createCallbackFcn(app, @APIAjouterButtonPushed, true);
            app.APIAjouterButton.Text = 'Ajouter';
            app.APIEditField_1 = uieditfield(app.APIAddPanel, 'numeric');
            app.DotLabel = uilabel(app.APIAddPanel);
            app.DotLabel.Text = '.';
            app.APIEditField_2 = uieditfield(app.APIAddPanel, 'numeric');
            app.DotLabel_2 = uilabel(app.APIAddPanel);
            app.DotLabel_2.Text = '.';
            app.APIEditField_3 = uieditfield(app.APIAddPanel, 'numeric');
            app.IdentifiantAPILabel = uilabel(app.APIAddPanel);
            app.IdentifiantAPILabel.Text = 'Identifiant (API)*';
            app.ManualCheckBox = uicheckbox(app.SimulationTab);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = '';
            app.APICheckBox = uicheckbox(app.SimulationTab);
            app.APICheckBox.ValueChangedFcn = createCallbackFcn(app, @APICheckBoxValueChanged, true);
            app.APICheckBox.Text = '';
            app.ExcelLabel = uilabel(app.SimulationTab);
            app.ExcelLabel.Text = 'Excel';
            app.APILabel = uilabel(app.SimulationTab);
            app.APILabel.Text = 'API';
            app.PourtrouverunepicedemonnaieLabel = uilabel(app.SimulationTab);
            app.PourtrouverunepicedemonnaieLabel.Text = '* Pour trouver une pièce de monnaie:';
            app.urlNumismatButton = uibutton(app.SimulationTab, 'push');
            app.urlNumismatButton.ButtonPushedFcn = createCallbackFcn(app, @urlNumismatButtonPushed, true);
            app.urlNumismatButton.Text = 'http://numismatics.org/search/results';
            app.GraphTabGroup = uitabgroup(app.SimulationTab);
            app.MasseTab = uitab(app.GraphTabGroup);
            app.MasseTab.Title = 'Masse';
            app.MasseAxes = uiaxes(app.MasseTab);
            title(app.MasseAxes, 'Masse sur le plateau')
            xlabel(app.MasseAxes, 'Temps (s)')
            ylabel(app.MasseAxes, 'Masse (g)')
            app.PositionTab = uitab(app.GraphTabGroup);
            app.PositionTab.Title = 'Position';
            app.PositionAxes = uiaxes(app.PositionTab);
            title(app.PositionAxes, 'Position du boût de la lame')
            xlabel(app.PositionAxes, 'Temps (s)')
            ylabel(app.PositionAxes, 'Position relative à x0 (mm)')
            app.TensionTab = uitab(app.GraphTabGroup);
            app.TensionTab.Title = 'Tension';
            app.TensionAxes = uiaxes(app.TensionTab);
            title(app.TensionAxes, 'Tension appliquée à l''amplificateur')
            xlabel(app.TensionAxes, 'Temps (s)')
            ylabel(app.TensionAxes, 'Masse (g)')
            app.CourantTab = uitab(app.GraphTabGroup);
            app.CourantTab.Title = 'Courant';
            app.CourantAxes = uiaxes(app.CourantTab);
            title(app.CourantAxes, 'Courant sur l''actionneur linéaire')
            xlabel(app.CourantAxes, 'Temps (s)')
            ylabel(app.CourantAxes, 'Courant (mA)')
            app.DuredesimulationLabel = uilabel(app.SimulationTab);
            app.DuredesimulationLabel.Text = 'Durée de simulation';
            app.NombredchantillonsEditFieldLabel = uilabel(app.SimulationTab);
            app.NombredchantillonsEditFieldLabel.Text = {'Nombre '; 'd''échantillons'};
            app.NombredchantillonsEditField = uieditfield(app.SimulationTab, 'numeric');
            app.NombredchantillonsEditField.ValueChangedFcn = createCallbackFcn(app, @NombredchantillonsEditFieldValueChanged, true);
            app.TempsparchantillonusEditFieldLabel = uilabel(app.SimulationTab);
            app.TempsparchantillonusEditFieldLabel.Text = {'Temps par '; 'échantillon (us)'};
            app.TempsparchantillonusEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TempsparchantillonusEditField.ValueChangedFcn = createCallbackFcn(app, @TempsparchantillonusEditFieldValueChanged, true);
            app.ActiverbalayageCheckBox = uicheckbox(app.SimulationTab);
            app.ActiverbalayageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiverbalayageCheckBoxValueChanged, true);
            app.ActiverbalayageCheckBox.Text = 'Activer balayage';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.DimensionsdelabalancePanel = uipanel(app.ConfigurationTab);
            app.DimensionsdelabalancePanel.Title = 'Dimensions de la balance';
            app.LongueurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LongueurcmEditFieldLabel.Text = 'Longueur (cm)';
            app.LongueurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.LongueurBalanceField.Editable = 'off';
            app.LargeurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.LargeurcmEditFieldLabel.Text = 'Largeur (cm)';
            app.LargeurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.LargeurBalanceField.Editable = 'off';
            app.HauteurcmEditFieldLabel = uilabel(app.DimensionsdelabalancePanel);
            app.HauteurcmEditFieldLabel.Text = 'Hauteur (cm)';
            app.HauteurBalanceField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.HauteurBalanceField.Editable = 'off';
            app.PositionduplateauSliderLabel = uilabel(app.DimensionsdelabalancePanel);
            app.PositionduplateauSliderLabel.Text = 'Position du plateau';
            app.PositionduplateauSlider = uislider(app.DimensionsdelabalancePanel);
            app.PositionduplateauSlider.ValueChangingFcn = createCallbackFcn(app, @PositionduplateauSliderValueChanging, true);
            app.UIDimension = uiaxes(app.DimensionsdelabalancePanel);
            title(app.UIDimension, '')
            xlabel(app.UIDimension, 'X (cm)')
            ylabel(app.UIDimension, 'Y (cm)')
            app.GainamplificateurLabel = uilabel(app.DimensionsdelabalancePanel);
            app.GainamplificateurLabel.Text = 'Gain amplificateur';
            app.GainAmplificateurEditField = uieditfield(app.DimensionsdelabalancePanel, 'numeric');
            app.GainAmplificateurEditField.ValueChangedFcn = createCallbackFcn(app, @GainAmplificateurEditFieldValueChanged, true);
            app.LameflexiblePanel = uipanel(app.ConfigurationTab);
            app.LameflexiblePanel.Title = 'Lame flexible';
            app.paisseurmmLabel = uilabel(app.LameflexiblePanel);
            app.paisseurmmLabel.Text = 'Épaisseur (mm)';
            app.EpaisseurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.EpaisseurLameField.ValueChangedFcn = createCallbackFcn(app, @EpaisseurLameFieldValueChanged, true);
            app.MassegEditFieldLabel = uilabel(app.LameflexiblePanel);
            app.MassegEditFieldLabel.Text = 'Masse (g)';
            app.MasseLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.MasseLameField.ValueChangedFcn = createCallbackFcn(app, @MasseLameFieldValueChanged, true);
            app.UILameAxes = uiaxes(app.LameflexiblePanel);
            title(app.UILameAxes, 'Dimensions de la lame')
            xlabel(app.UILameAxes, '')
            ylabel(app.UILameAxes, '')
            app.UILameAxes.XLim = [0 15];
            app.UILameAxes.YLim = [0 25];
            app.UILameAxes.ZLim = [-3 3];
            app.UILameAxes.XTick = [0 5 10];
            app.LongueurcmLabel = uilabel(app.LameflexiblePanel);
            app.LongueurcmLabel.Text = 'Longueur (cm)';
            app.LongueurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.LongueurLameField.ValueChangedFcn = createCallbackFcn(app, @LongueurLameFieldValueChanged, true);
            app.LargeurcmLabel = uilabel(app.LameflexiblePanel);
            app.LargeurcmLabel.Text = 'Largeur (cm)';
            app.LargeurLameField = uieditfield(app.LameflexiblePanel, 'numeric');
            app.LargeurLameField.ValueChangedFcn = createCallbackFcn(app, @LargeurLameFieldValueChanged, true);
            app.LamePlusButton = uibutton(app.LameflexiblePanel, 'push');
            app.LamePlusButton.ButtonPushedFcn = createCallbackFcn(app, @LamePlusButtonPushed, true);
            app.LamePlusButton.Text = 'plus ...';
            app.CapteurcapacitifPanel = uipanel(app.ConfigurationTab);
            app.CapteurcapacitifPanel.Title = 'Capteur capacitif';
            app.UICaptAxes = uiaxes(app.CapteurcapacitifPanel);
            title(app.UICaptAxes, 'Dimensions du capteur')
            xlabel(app.UICaptAxes, '')
            ylabel(app.UICaptAxes, '')
            app.UICaptAxes.ZLim = [0 6];
            app.EspacementmmSliderLabel = uilabel(app.CapteurcapacitifPanel);
            app.EspacementmmSliderLabel.Text = {'Espacement'; '(mm)'};
            app.EspacementmmSlider = uislider(app.CapteurcapacitifPanel);
            app.EspacementmmSlider.Limits = [1 5];
            app.EspacementmmSlider.ValueChangingFcn = createCallbackFcn(app, @EspacementmmSliderValueChanging, true);
            app.EspacementmmSlider.Value = 1;
            app.CaptPlusButton = uibutton(app.CapteurcapacitifPanel, 'push');
            app.CaptPlusButton.ButtonPushedFcn = createCallbackFcn(app, @CaptPlusButtonPushed, true);
            app.CaptPlusButton.Text = 'plus ...';
            app.V_EditFieldLabel = uilabel(app.CapteurcapacitifPanel);
            app.V_EditFieldLabel.Text = 'Vᵢₙ_ₘₐₓ =';
            app.VimEditField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.VimEditField.ValueChangedFcn = createCallbackFcn(app, @VimEditFieldValueChanged, true);
            app.VLabel = uilabel(app.CapteurcapacitifPanel);
            app.VLabel.Text = 'Vₜₕ =';
            app.VthEditField = uieditfield(app.CapteurcapacitifPanel, 'numeric');
            app.VthEditField.ValueChangedFcn = createCallbackFcn(app, @VthEditFieldValueChanged, true);
            app.ArchivePanel = uipanel(app.ConfigurationTab);
            app.ArchivePanel.Title = 'Archive';
            app.HistoriquedesconfigurationsListBoxLabel = uilabel(app.ArchivePanel);
            app.HistoriquedesconfigurationsListBoxLabel.Text = 'Historique des configurations';
            app.HistoriquedesconfigurationsListBox = uilistbox(app.ArchivePanel);
            app.HistoriquedesconfigurationsListBox.Items = {};
            app.HistoriquedesconfigurationsListBox.Value = {};
            app.ParcourirLoadButton = uibutton(app.ArchivePanel, 'push');
            app.ParcourirLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ParcourirLoadButtonPushed, true);
            app.ParcourirLoadButton.Text = 'Parcourir...';
            app.LoadEditField = uieditfield(app.ArchivePanel, 'text');
            app.LoadEditField.Editable = 'off';
            app.LoadEditField.Value = '<Autre configuration>';
            app.ChargeruneautreconfigurationLabel = uilabel(app.ArchivePanel);
            app.ChargeruneautreconfigurationLabel.Text = 'Charger une autre configuration';
            app.NettoyerhistoriqueButton = uibutton(app.ArchivePanel, 'push');
            app.NettoyerhistoriqueButton.ButtonPushedFcn = createCallbackFcn(app, @NettoyerhistoriqueButtonPushed, true);
            app.NettoyerhistoriqueButton.Text = {'Nettoyer'; 'historique'};
            app.AjouterConfigButton = uibutton(app.ArchivePanel, 'push');
            app.AjouterConfigButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterConfigButtonPushed, true);
            app.AjouterConfigButton.Text = {'Sauvegarder'; 'configuration'};
            app.ChargerlaslectionButton = uibutton(app.ArchivePanel, 'push');
            app.ChargerlaslectionButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerlaslectionButtonPushed, true);
            app.ChargerlaslectionButton.Text = {'Charger'; 'la sélection'};
            app.SquencedesimulationPanel = uipanel(app.ConfigurationTab);
            app.SquencedesimulationPanel.Title = 'Séquence de simulation';
            app.vnementsLabel = uilabel(app.SquencedesimulationPanel);
            app.vnementsLabel.Text = 'Évènements';
            app.UIEventTable = uitable(app.SquencedesimulationPanel);
            app.UIEventTable.ColumnName = {'Moment (us)'; 'Action'; 'Pièce (ligne)'};
            app.UIEventTable.RowName = {};
            app.UIEventTable.ColumnSortable = false;
            app.UIEventTable.ColumnEditable = true;
            app.UIEventTable.CellEditCallback = createCallbackFcn(app, @UIEventTableCellEdit, true);
            app.AjoutervenementButton = uibutton(app.SquencedesimulationPanel, 'push');
            app.AjoutervenementButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutervenementButtonPushed, true);
            app.AjoutervenementButton.Text = {'Ajouter '; 'un évènement'};
            app.EnelvervenementButton = uibutton(app.SquencedesimulationPanel, 'push');
            app.EnelvervenementButton.ButtonPushedFcn = createCallbackFcn(app, @EnelvervenementButtonPushed, true);
            app.EnelvervenementButton.Text = {'Enlever '; 'un évènement'};
            app.ParcourirLoadSimuButton = uibutton(app.SquencedesimulationPanel, 'push');
            app.ParcourirLoadSimuButton.ButtonPushedFcn = createCallbackFcn(app, @ParcourirLoadSimuButtonPushed, true);
            app.ParcourirLoadSimuButton.Text = 'Parcourir...';
            app.LoadSimuEditField = uieditfield(app.SquencedesimulationPanel, 'text');
            app.LoadSimuEditField.Editable = 'off';
            app.LoadSimuEditField.Value = '<Simulation>';
            app.ChargerunesimulationLabel = uilabel(app.SquencedesimulationPanel);
            app.ChargerunesimulationLabel.Text = 'Charger une simulation';
            app.ActionneurlineairePanel = uipanel(app.ConfigurationTab);
            app.ActionneurlineairePanel.Title = 'Actionneur linéaire';
            app.RayoncmLabel = uilabel(app.ActionneurlineairePanel);
            app.RayoncmLabel.Text = 'Rayon (cm)';
            app.RayonField = uieditfield(app.ActionneurlineairePanel, 'numeric');
            app.RayonField.ValueChangedFcn = createCallbackFcn(app, @RayonFieldValueChanged, true);
            app.UIActionAxes = uiaxes(app.ActionneurlineairePanel);
            title(app.UIActionAxes, 'Dimensions de l''actionneur')
            xlabel(app.UIActionAxes, '')
            ylabel(app.UIActionAxes, '')
            app.UIActionAxes.ZTick = [];
            app.UIActionAxes.ZTickLabel = '';
            app.HauteurcmLabel = uilabel(app.ActionneurlineairePanel);
            app.HauteurcmLabel.Text = 'Hauteur (cm)';
            app.HauteurActionEditField = uieditfield(app.ActionneurlineairePanel, 'numeric');
            app.HauteurActionEditField.ValueChangedFcn = createCallbackFcn(app, @HauteurActionEditFieldValueChanged, true);
            app.ActionPlusButton = uibutton(app.ActionneurlineairePanel, 'push');
            app.ActionPlusButton.ButtonPushedFcn = createCallbackFcn(app, @ActionPlusButtonPushed, true);
            app.ActionPlusButton.Text = 'plus ...';
            app.ChampsTLabel = uilabel(app.ActionneurlineairePanel);
            app.ChampsTLabel.Text = 'Champs (T)';
            app.ActionChampsEditField = uieditfield(app.ActionneurlineairePanel, 'numeric');
            app.ActionChampsEditField.ValueChangedFcn = createCallbackFcn(app, @ActionChampsEditFieldValueChanged, true);
            app.MassegLabel = uilabel(app.ActionneurlineairePanel);
            app.MassegLabel.Text = 'Masse (g)';
            app.ActionMasseEditField = uieditfield(app.ActionneurlineairePanel, 'numeric');
            app.ActionMasseEditField.ValueChangedFcn = createCallbackFcn(app, @ActionMasseEditFieldValueChanged, true);
            app.BalayageTab = uitab(app.TabGroup);
            app.BalayageTab.Title = 'Balayage';
            app.UIBalayageTable = uitable(app.BalayageTab);
            app.UIBalayageTable.ColumnName = {'Paramètre'; 'Valeur Initiale'; 'Valeur Finale'; 'Taille du step'};
            app.UIBalayageTable.RowName = {};
            app.UIBalayageTable.ColumnSortable = false;
            app.UIBalayageTable.ColumnEditable = true;
            app.UIBalayageTable.CellEditCallback = createCallbackFcn(app, @UIBalayageTableCellEdit, true);
            app.ConfigurationspourbalayageLabel = uilabel(app.BalayageTab);
            app.ConfigurationspourbalayageLabel.Text = 'Configurations pour balayage';
            % Show the figure after all components are created
            app.SimulateurdebalanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainCAO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulateurdebalanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulateurdebalanceUIFigure)
        end
    end
end